classdef MYKI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Message                        matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        ClassifierParametersTab        matlab.ui.container.Tab
        ElbowfilterPanel               matlab.ui.container.Panel
        CalibratemagposButton          matlab.ui.control.Button
        SendfilterparametersButton     matlab.ui.control.Button
        EnableFilterCheckBox           matlab.ui.control.CheckBox
        FilterthresholdmmEditField     matlab.ui.control.NumericEditField
        FilterthresholdmmEditFieldLabel  matlab.ui.control.Label
        Filterwindow150msEditField     matlab.ui.control.NumericEditField
        Filterwindow150msEditFieldLabel  matlab.ui.control.Label
        HoldtoopenparametersPanel      matlab.ui.container.Panel
        SendswitchparametersButton     matlab.ui.control.Button
        ThresholdtoswitchEditField     matlab.ui.control.NumericEditField
        ThresholdtoswitchEditFieldLabel  matlab.ui.control.Label
        Waitforswitch150msEditField    matlab.ui.control.NumericEditField
        Waitforswitch150msEditFieldLabel  matlab.ui.control.Label
        EnableSwitchCheckBox           matlab.ui.control.CheckBox
        ClassonBioPatRectoMIAlegendPanel  matlab.ui.container.Panel
        Lateral6Label                  matlab.ui.control.Label
        Pinch4Label                    matlab.ui.control.Label
        Openhand5Label                 matlab.ui.control.Label
        Cylindrical3Label              matlab.ui.control.Label
        ButtoncolorlegendPanel         matlab.ui.container.Panel
        RemovingconnectionwiththeboardLabel  matlab.ui.control.Label
        ObtainingparameterswithingtheappLabel  matlab.ui.control.Label
        EnablingDiablingcontrolontheboardLabel  matlab.ui.control.Label
        LoadingdataontheappLabel       matlab.ui.control.Label
        SendingcommandtoboardLabel     matlab.ui.control.Label
        FeaturessettingsPanel          matlab.ui.container.Panel
        PostprocessingalgorithmButtonGroup  matlab.ui.container.ButtonGroup
        GrasplockButton                matlab.ui.control.RadioButton
        MajorityvoteButton             matlab.ui.control.RadioButton
        NoneButton                     matlab.ui.control.RadioButton
        AllowpatternrecognitionButton  matlab.ui.control.StateButton
        WaitforMVEditField             matlab.ui.control.NumericEditField
        WaitforMV150msEditFieldLabel   matlab.ui.control.Label
        WaitforODAEditField            matlab.ui.control.NumericEditField
        WaitforODA150msEditField_2Label  matlab.ui.control.Label
        FeaturesPanel                  matlab.ui.container.Panel
        LastvalueCheckBox              matlab.ui.control.CheckBox
        SlopeCheckBox                  matlab.ui.control.CheckBox
        WLCheckBox                     matlab.ui.control.CheckBox
        MinCheckBox                    matlab.ui.control.CheckBox
        MaxCheckBox                    matlab.ui.control.CheckBox
        MedianCheckBox                 matlab.ui.control.CheckBox
        MAVCheckBox                    matlab.ui.control.CheckBox
        SendfeatparamButton            matlab.ui.control.Button
        OverlapwindowsamplesEditField  matlab.ui.control.EditField
        OverlapwindowsamplesEditFieldLabel  matlab.ui.control.Label
        TimewindowmsEditField          matlab.ui.control.EditField
        TimewindowmsEditFieldLabel     matlab.ui.control.Label
        PatternRecognitionClassifierPanel  matlab.ui.container.Panel
        ChoosechannelsButton           matlab.ui.control.Button
        EnableDisablepropctrlButton    matlab.ui.control.Button
        ProportionalcontrolCheckBox    matlab.ui.control.CheckBox
        UploadtoboardButton            matlab.ui.control.Button
        NumclassesLabel                matlab.ui.control.Label
        MagFeaturesLabel               matlab.ui.control.Label
        FeaturesLabel                  matlab.ui.control.Label
        ClassifierLabel                matlab.ui.control.Label
        LoadfromfileButton             matlab.ui.control.Button
        ConnectionPanel                matlab.ui.container.Panel
        RefreshCOMButton               matlab.ui.control.Button
        DisconnetctButton              matlab.ui.control.Button
        ChooseCOMDropDown              matlab.ui.control.DropDown
        ChooseCOMDropDownLabel         matlab.ui.control.Label
        ConnectButton                  matlab.ui.control.Button
        StreamingTab                   matlab.ui.container.Tab
        FlushtheserialButton           matlab.ui.control.Button
        AdditionalinfoPanel            matlab.ui.container.Panel
        MagnetpairdistancesLabel       matlab.ui.control.Label
        Mot3Label                      matlab.ui.control.Label
        Enc3Label                      matlab.ui.control.Label
        Mot2Label                      matlab.ui.control.Label
        Enc2Label                      matlab.ui.control.Label
        Mot1Label                      matlab.ui.control.Label
        EncodervaluesLabel             matlab.ui.control.Label
        Enc1Label                      matlab.ui.control.Label
        C3Label                        matlab.ui.control.Label
        C2Label                        matlab.ui.control.Label
        C1Label                        matlab.ui.control.Label
        Pair3Label                     matlab.ui.control.Label
        Pair2Label                     matlab.ui.control.Label
        Pair1Label                     matlab.ui.control.Label
        MVCperchannelsLabel            matlab.ui.control.Label
        Dist3Label                     matlab.ui.control.Label
        Dist2Label                     matlab.ui.control.Label
        Dist1Label                     matlab.ui.control.Label
        MVC33Label                     matlab.ui.control.Label
        MVC32Label                     matlab.ui.control.Label
        MVC31Label                     matlab.ui.control.Label
        MVC23Label                     matlab.ui.control.Label
        MVC22Label                     matlab.ui.control.Label
        MVC21Label                     matlab.ui.control.Label
        MVC13Label                     matlab.ui.control.Label
        MVC12Label                     matlab.ui.control.Label
        MVC11Label                     matlab.ui.control.Label
        dist3Label                     matlab.ui.control.Label
        dist2Label                     matlab.ui.control.Label
        dist1Label                     matlab.ui.control.Label
        PPTypeLabel                    matlab.ui.control.Label
        PostprocessingalgorithmLabel   matlab.ui.control.Label
        TypeLabel                      matlab.ui.control.Label
        ClassifierLabel_2              matlab.ui.control.Label
        ForceLabel                     matlab.ui.control.Label
        ProportionalcontrolLabel       matlab.ui.control.Label
        ClassLabel                     matlab.ui.control.Label
        PredictedclassLabel            matlab.ui.control.Label
        StreamButton                   matlab.ui.control.StateButton
        UIAxes                         matlab.ui.control.UIAxes
        RestandMaxdispTab              matlab.ui.container.Tab
        ChannelsListBox_2              matlab.ui.control.ListBox
        ChannelsListBox_2Label         matlab.ui.control.Label
        UploadMagnetPairsButton        matlab.ui.control.Button
        UploadDataButton               matlab.ui.control.Button
        MaximumDisplacement            matlab.ui.control.Label
        MaximumdisplacementLabel       matlab.ui.control.Label
        RestDistance                   matlab.ui.control.Label
        RestdistanceLabel              matlab.ui.control.Label
        MagnetPairs                    matlab.ui.control.Label
        MagnetPairsLabel               matlab.ui.control.Label
        LoadDataButton                 matlab.ui.control.Button
        MultilinearRegressorTab        matlab.ui.container.Tab
        MLRvisualizationPanel          matlab.ui.container.Panel
        MLRfittingPanel                matlab.ui.container.Panel
        MLRcontrolPanel                matlab.ui.container.Panel
        HandcontrolButton              matlab.ui.control.StateButton
        Gain5EditField                 matlab.ui.control.NumericEditField
        DoF5EditField_2Label           matlab.ui.control.Label
        Gain4EditField                 matlab.ui.control.NumericEditField
        DoF4EditField_2Label           matlab.ui.control.Label
        Gain3EditField                 matlab.ui.control.NumericEditField
        DoF3EditField_2Label           matlab.ui.control.Label
        Gain2EditField                 matlab.ui.control.NumericEditField
        DoF2EditField_2Label           matlab.ui.control.Label
        Gain1EditField                 matlab.ui.control.NumericEditField
        DoF1EditField_2Label           matlab.ui.control.Label
        GainsLabel                     matlab.ui.control.Label
        ThresholdsLabel                matlab.ui.control.Label
        SendctrlparamButton            matlab.ui.control.Button
        DoF5EditField                  matlab.ui.control.NumericEditField
        DoF5EditFieldLabel             matlab.ui.control.Label
        DoF4EditField                  matlab.ui.control.NumericEditField
        DoF4EditFieldLabel             matlab.ui.control.Label
        DoF3EditField                  matlab.ui.control.NumericEditField
        DoF3EditFieldLabel             matlab.ui.control.Label
        DoF2EditField                  matlab.ui.control.NumericEditField
        DoF2EditFieldLabel             matlab.ui.control.Label
        DoF1EditField                  matlab.ui.control.NumericEditField
        DoF1EditFieldLabel             matlab.ui.control.Label
        ControlmodalityButtonGroup     matlab.ui.container.ButtonGroup
        PositionButton                 matlab.ui.control.RadioButton
        VelocityButton                 matlab.ui.control.RadioButton
        EnableMLRButton                matlab.ui.control.StateButton
        UploadMLRparametersButton      matlab.ui.control.Button
        LoadfeaturesButton             matlab.ui.control.Button
        TrainMLRButton                 matlab.ui.control.Button
        ChannelsListBox                matlab.ui.control.ListBox
        ChannelsListBoxLabel           matlab.ui.control.Label
        DoFListBox                     matlab.ui.control.ListBox
        DoFListBoxLabel                matlab.ui.control.Label
        FeaturesListBox                matlab.ui.control.ListBox
        FeaturesListBoxLabel           matlab.ui.control.Label
        ReadSavedparametersTab         matlab.ui.container.Tab
        MultilinearRegressorparametersLabel  matlab.ui.control.Label
        PatternRecognitionparametersLabel  matlab.ui.control.Label
        GeneralparametersLabel         matlab.ui.control.Label
        ReadtheparametersButton        matlab.ui.control.Button
    end

    properties
        obj
        patRec
        sigFeat
        dataRestMax
        diagBox
        mlrParam
    end
    properties(Constant)
        % 14 is constant, the second 9 is magPairs, 12 is 6*2 where 6 is the num of
        % features and 2 is for magnet features, 5 is the number of
        % classes, 4 is the bytes for float
        msgLength = 14 + 9*2 + 9*12*4*2 +  5*4 + 5*9*12*4; 
        maxMagnetPairs = 9;
        maxClasses = 5;
        maxDof = 6;
        maxFeatures = 6;
        DofNames = {'Index flexion', 'Extension', 'Middle flexion','Thumb flexion','Thumb opposition'};
    end

    methods (Access = public)
        function UpdateProstheticOutput(app, array)
            app.patRec.prostheticOutput = array;
        end

        function [coeff_mvreg, sigma, RESID, meanD, stdD] = multiLinRegressor(app,data,labels) 
            nFeaturesUsed = length(app.FeaturesListBox.Value);
            nChannelsUsed = str2num(cell2mat(app.ChannelsListBox.Value'));
            nChannelsUsed = sort(nChannelsUsed);
            rowsToTake = nChannelsUsed + app.sigFeat.nCh(end-1)*(0:nFeaturesUsed-1);
            rowsToTake = reshape(rowsToTake,1,[]);
            data = data(:,rowsToTake,:);
            nFeatures = size(data,2);
            % nSamplesWindowedTime = time Series Windowed
            nSamplesWindowedTime = size(data,1);
            NDOF = size(data,3);
            
            % MAT_FEATURES: (samples*Dof) x features
            MAT_FEATURES = zeros(nSamplesWindowedTime*NDOF,nFeatures);
            
            labelsSig = zeros(nSamplesWindowedTime*NDOF,NDOF);
            
            for indDof = 1:NDOF
                indIniNewDof = nSamplesWindowedTime*(indDof-1);
                MAT_FEATURES((1:nSamplesWindowedTime) + indIniNewDof,:) = data(:,:,indDof);
                labelsSig((1:nSamplesWindowedTime) + indIniNewDof,indDof) = labels(:,indDof);
            end

%             normalizing the data (z-score)
            meanD = mean(MAT_FEATURES);
            stdD = std(MAT_FEATURES);
            MAT_FEATURES = (MAT_FEATURES - meanD)./stdD;  
            
            if (nFeatures > 6)
                sPlot1 = fix(nFeatures/2) + 1;
                sPlot2 = 2;
            else
                sPlot1 = nFeatures + 1;
                sPlot2 = 1;
            end
            figure 
            for indFeat = 1:nFeatures
                subplot(sPlot1,sPlot2,indFeat)
                plot(MAT_FEATURES(:,indFeat));
                chStr = num2str(mod(indFeat-1,length(nChannelsUsed))+1);
                featStr = num2str(fix((indFeat-1)/length(nChannelsUsed))+1);
                title(['Feature ' featStr '; Channel ' chStr])
            end
            subplot(nFeatures+1,1,nFeatures+1)
            plot(labelsSig)
            title('Ramp signal')
            
            [coeff_mvreg,sigma, RESID ] = mvregress([ones(nSamplesWindowedTime*NDOF,1),MAT_FEATURES], labelsSig);

%             figure 
%             for indDof = 1:NDOF
%                 subplot(NDOF,1,indDof)
%                 hold on
%                 plot(labelsSig(:,indDof),'b')
%                 plot([ones(nSamplesWindowedTime*NDOF,1),MAT_FEATURES]*coeff_mvreg(:,indDof),'r')
%                 title(['DOF' num2str(indDof)])
%                 legend('Ramp','Real sig')
%                 hold off
%             end
            for indDof = 1:NDOF
                ax{indDof} = subplot(NDOF,1,indDof,'Parent',app.MLRvisualizationPanel);
                cla(ax{indDof})
                hold(ax{indDof},'on')
                plot(ax{indDof},labelsSig(:,indDof),'b');
                plot(ax{indDof},[ones(nSamplesWindowedTime*NDOF,1),MAT_FEATURES]*coeff_mvreg(:,indDof),'r');
                title(ax{indDof},['DOF' num2str(indDof)]);
                hold(ax{indDof},'off')
            end
        end
    end

    methods (Access = private)
        
        function SendCommand(app,command)
            comm = ['@', command, '#'];
            for i = 1:length(comm)
                write(app.obj,comm(i),'char');
            end
        end

        function array = Splitfloat(app,doubleArray) % split an array of float32 into an array of 4 uint8
            array = uint8(zeros(1,length(doubleArray)*4));
            for i = 1:length(doubleArray)
                temp = typecast(single(doubleArray(i)),'uint8');
                array((1:4)+4*(i-1)) = temp(end:-1:1);
%                 array((1:4)+4*(i-1)) = temp;
            end
        end

        function UpdateFeatureCheckbox(app)
            app.MAVCheckBox.Value = 0;
            app.MedianCheckBox.Value = 0;
            app.MaxCheckBox.Value = 0;
            app.MinCheckBox.Value = 0;
            app.WLCheckBox.Value = 0;
            app.SlopeCheckBox.Value = 0;
            app.LastvalueCheckBox.Value = 0;
            if(sum(strcmp(app.patRec.selFeatures,'tmabsTr1')))
                app.MAVCheckBox.Value = 1;
            end
            if(sum(strcmp(app.patRec.selFeatures,'tmd')))
                app.MedianCheckBox.Value = 1;
            end
            if(sum(strcmp(app.patRec.selFeatures,'tmax')))
                app.MaxCheckBox.Value = 1;
            end
            if(sum(strcmp(app.patRec.selFeatures,'tmin')))
                app.MinCheckBox.Value = 1;
            end
            if(sum(strcmp(app.patRec.selFeatures,'twl')))
                app.WLCheckBox.Value = 1;
            end
            if(sum(strcmp(app.patRec.selFeatures,'mslp')))
                app.SlopeCheckBox.Value = 1;
            end
            if(sum(strcmp(app.patRec.selFeatures,'mlv')))
                app.LastvalueCheckBox.Value = 1;
            end
        end

        function featureCode = CreateFeatureCode(app, features)
            featureCode = uint8(0); 
            if sum(contains(features,'tmabsTr1'))
                featureCode = bitor(featureCode,8);
            end
            if sum(contains(features,'tmd'))
                featureCode = bitor(featureCode,1);
            end
            if sum(contains(features,'tmax'))
                featureCode = bitor(featureCode,2);
            end
            if sum(contains(features,'tmin'))
                featureCode = bitor(featureCode,4);
            end
            if sum(contains(features,'twl'))
                featureCode = bitor(featureCode,16);
            end
            if sum(contains(features,'mslp'))
                featureCode = bitor(featureCode,32);
            end
            if sum(contains(features,'mlv'))
                featureCode = bitor(featureCode,64);
            end
        end

        function EnableDisableAllCheckBoxes(app, value)
            if value
                str = "on";
            else
                str = "off";
            end
            app.MAVCheckBox.Enable    = str;
            app.MedianCheckBox.Enable = str;
            app.MaxCheckBox.Enable    = str;
            app.MinCheckBox.Enable    = str;
            app.WLCheckBox.Enable     = str;
            app.SlopeCheckBox.Enable  = str;
            app.LastvalueCheckBox.Enable = str;
        end

        function classStr = InterpretClass(app,class)
            switch (class)
                case 3
                    classStr = "Close hand";
                case 5
                    classStr = "Open hand";
                case 4
                    classStr = "Bidigital grasp";
                case 6
                    classStr = "Lateral grasp";
                case 0
                    classStr = "Rest";
                otherwise
                    classStr = "Not a class";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ChooseCOMDropDown
        function ChooseCOMDropDownValueChanged(app, event)
            value = app.ChooseCOMDropDown.Value;
            
        end

        % Drop down opening function: ChooseCOMDropDown
        function ChooseCOMDropDownOpening(app, event)
            ports = serialportlist;
            if ~isempty(ports)
                app.ChooseCOMDropDown.Items = ports;
            else
                app.ChooseCOMDropDown.Items = {'None Available'};
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            ComPortName = app.ChooseCOMDropDown.Value;
            if ~contains(ComPortName,'COM')
                app.Message.Text = 'Invalid COM port';
                drawnow();
                return
            end
            app.Message.Text = 'Testing Connection...';
            drawnow();
            BaudRate = 921600;
            delete(instrfindall);
            if isempty(app.obj)
                app.obj = serialport(ComPortName, BaudRate, 'Databits', 8, 'Byteorder', 'big-endian');
            end
%             app.obj = serial(ComPortName, 'baudrate', BaudRate, 'databits', 8, 'byteorder', 'bigEndian');
%             fclose(app.obj);
%             fopen(app.obj);
            flush(app.obj);
            % stop the streaming
            SendCommand(app,'A0');
            SendCommand(app,'B0');
            pause(0.5)
            app.Message.Text = 'Connection established';
%             fclose(app.obj);
        end

        % Button pushed function: RefreshCOMButton
        function RefreshCOMButtonPushed(app, event)
            ports = serialportlist;
            if ~isempty(ports)
                % Show all available ports
                app.ChooseCOMDropDown.Items = ports;
            else
                app.ChooseCOMDropDown.Items = {'None Available'};
            end
        end

        % Button pushed function: LoadfromfileButton
        function LoadfromfileButtonPushed(app, event)
            app.Message.Text = 'Loading parameters file...';
            [file, path] = uigetfile({'*.mat';'*.csv'});
            if ~isequal(file, 0)
                load([path,file]); % load patRec file 
                if exist('patRec','var')
                    app.patRec = patRec;
                    if strcmp(app.patRec.patRecTrained.algorithm,'SVMt')
                        app.ClassifierLabel.Text = 'SVM transient';
                    elseif contains(app.patRec.patRecTrained.algorithm,'SVM')
                        app.ClassifierLabel.Text = 'SVM steady state';
                    else
                        app.ClassifierLabel.Text = app.patRec.patRecTrained.algorithm;
                    end
                    str = [];
                    for i = 1:length(app.patRec.selFeatures)
                        str = [str, app.patRec.selFeatures{i},', '];
                    end
                    str(end-1:end) = [];
                    app.FeaturesLabel.Text = str; % features used
                    if (length(app.patRec.magnetData.Features) == 2)
                        % magnet features used
                        app.MagFeaturesLabel.Text = 'Lin. and Ang. displacement';
                    else
                        app.MagFeaturesLabel.Text = app.patRec.magnetData.Features{1};
                    end
                    app.NumclassesLabel.Text = [num2str(app.patRec.nM), ' classes'];
                    app.Message.Text = 'Loaded PatRec';
                    UpdateFeatureCheckbox(app);
                    EnableDisableAllCheckBoxes(app,0);
                    % In case steady state SVM with ODA is chosen, it is
                    % reaquired to load also the file with ODA thresholds
                    % obtained with tSVM
%                     if (app.SteadyStateODAButton.Value)
%                         uiwait(msgbox("Choose file with thresholds for ODA","Success","modal"));
% %                         msgbox("Choose file with thresholds for ODA");
%                         [file, path] = uigetfile({'*.mat';'*.csv'});
%                         if ~isequal(file, 0)
%                            thr = load([path,file]);
%                            app.patRec.dThreshold = thr.dThreshold;
%                            app.patRec.threshold = thr.threshold;
%                         end
%                     end
                    app.UploadtoboardButton.Enable = "on";
%                     app.EnableDisablepropctrlButton.Enable = "on";
                    app.TimewindowmsEditField.Value = num2str(app.patRec.tW*1000);
                else
                    app.Message.Text = 'Wrong file!';
                end
            end
        end

        % Button pushed function: UploadtoboardButton
        function UploadtoboardButtonPushed(app, event)
            if isempty(app.patRec)
                app.Message.Text = "PatRec is not loaded!";
                return
            end
            if ~isfield(app.patRec,'prostheticOutput')
                app.diagBox = diagApp(app);
                waitfor(app.diagBox);
            end
            if ~isfield(app.patRec,'dThreshold')
                app.patRec.dThreshold = 1;
                app.patRec.threshold = 0;
            end
            nPairs = uint8(size(app.patRec.magnetData.pairs,1));
            nFeat  = uint8(length(app.patRec.selFeatures));
            nCh    = uint8(length(app.patRec.nCh));
            magPairs = uint8(reshape(app.patRec.magnetData.pairs',1,2*nPairs));
            prostheticOut = uint8(app.patRec.prostheticOutput');
            if strcmp(app.patRec.normSets.type,'Midrange0Range2')
                normParameter1 = app.patRec.normSets.midrange;
                normParameter2 = app.patRec.normSets.range;
                normParameter1 = Splitfloat(app,normParameter1);
                normParameter2 = Splitfloat(app,normParameter2);
            end
%                 msgL1 = app.msgLength + 8; % + 8 is for the 2 ODA thresholds which are float
            msgL = 9 + app.maxClasses + app.maxMagnetPairs*2 + app.maxMagnetPairs*app.maxFeatures*2*2*4 + 8 + app.maxClasses*4 + app.maxMagnetPairs*app.maxFeatures*2*app.maxClasses*4;
            header = 'PRT';
            classes = uint8(app.patRec.nM);
            if strcmp(app.patRec.patRecTrained.algorithm,'SVMt')
                classifierCode = uint8(2);
                nM = uint8(app.patRec.nM) - 1; % transient doesn't classify rest
%                     prostheticOut(end) = [];
            else
                classifierCode = uint8(1);
                nM = uint8(app.patRec.nM);
            end
            bias = zeros(1,nM);
            w    = zeros(1,nM*nFeat*nCh);
            for i = 1:nM
                % put classifier parameters in an array 
                bias(i) = app.patRec.patRecTrained.SVM{i}.Bias;
                w((1:nFeat*nCh)+nFeat*nCh*(i-1)) = app.patRec.patRecTrained.SVM{i}.Beta';
            end
            % change the classifier parameters from float to 4 uint8
            % bytes, so they can be transfered
            bias = Splitfloat(app,bias);
            w = Splitfloat(app,w);
            dThreshold = Splitfloat(app,app.patRec.dThreshold);
            threshold  = Splitfloat(app,app.patRec.threshold);
            featureCodeMag = uint8(0); % this value contains which featuers for magnets are used
            if (length(app.patRec.magnetData.Features) == 2)
                featureCodeMag = bitor(featureCodeMag,2^7+2^6);
            elseif contains(app.patRec.magnetData.Features{1},'Lin')
                featureCodeMag = bitor(featureCodeMag,2^7);
            else
                app.Message.Text = 'Error: Linear distance is not included!';
                return;
            end
            featureCode = CreateFeatureCode(app,app.patRec.selFeatures); % this value contains which featuers are used for pattern recognition
            % creating a command to be sent
            command = [header, classifierCode, classes, featureCodeMag, featureCode, uint8(nFeat*nCh), ...
                prostheticOut, nPairs, magPairs, normParameter1,...
                normParameter2, dThreshold, threshold, bias, w];
            if length(command)~=msgL
                command = [command, uint8(zeros(1,msgL-length(command)))];
            end
            if ~isempty(app.obj)
                SendCommand(app,command);
                app.Message.Text = 'Classifier parameters sent';
            else
                app.Message.Text = 'No connection!';
            end
        end

        % Button pushed function: SendfeatparamButton
        function SendfeatparamButtonPushed(app, event)
            tW = str2num(app.TimewindowmsEditField.Value);
            tW = uint8(tW/50); % minimum time window is 50ms
            oW = uint8(str2num(app.OverlapwindowsamplesEditField.Value));
            waitODA = uint8(app.WaitforODAEditField.Value);
            waitMV  = uint8(app.WaitforMVEditField.Value);
%             featCode = CreateFeatureCode(app,app.patRec.selFeatures);
            postProcessMode = uint8(0);
            if app.MajorityvoteButton.Value
                postProcessMode = uint8(1);
            elseif app.GrasplockButton.Value
                postProcessMode = uint8(2);
            end
%             if app.SteadyStateODAButton.Value || app.TransientButton.Value
%                 classifierCode = uint8(1+app.TransientButton.Value);
%                 featCode = CreateFeatureCode(app);
%             else
%                 classifierCode = uint8(3);
%                 featCode = featCode + 2^6;
%             end
            header = 'PRF';
            command = [header, tW, oW, waitODA, waitMV, postProcessMode];
            SendCommand(app,command);
            app.Message.Text = 'Feature parameters are sent!';
        end

        % Button pushed function: EnableDisablepropctrlButton
        function EnableDisablepropctrlButtonPushed(app, event)
            flagPC = uint8(app.ProportionalcontrolCheckBox.Value);
            header = 'PRP';
            command = [header, flagPC];
            SendCommand(app,command);
        end

        % Value changed function: StreamButton
        function StreamButtonValueChanged(app, event)
            value = app.StreamButton.Value;
            % Setting the axes for the MLR outputs
            app.UIAxes.XLim = [0 6];
            app.UIAxes.YLim = [-1 2];
            app.UIAxes.XTick = 1:5;
            app.UIAxes.XTickLabel = {'IndF','Ext','MlrF','ThuF','ThuO'};
            app.UIAxes.XLabel.String = 'DOFs';
            app.UIAxes.YLabel.String = 'MLR output';
%             app.MVCTable.Data(:,1) = 1:3;
            if value % Button pushed
                % Disable the streaming of board poses and board
                % acquisitions
                SendCommand(app,'B0');
                pause(0.5);
                SendCommand(app,'A0');
                % wait for commands to fo through
                pause(0.5);
                app.Message.Text = "Streaming started";
                drawnow;
                cntSP = 0;
                cntMVC = 0;
                flagPlotThreshold = 1;
                while(app.StreamButton.Value) % constantly read the buttons value
                    % Conters for things that don't have to be updated
                    % every itteration
                    cntSP = cntSP + 1;
                    cntMVC = cntMVC + 1;
                    % Reading the streaming message
                    msg = read(app.obj,150,'char');
                    % Searching for the part related to Pattern recognition
                    % or MLR
                    idx = strfind(msg,'prc'); % header for pattern recognition
                    idxM = strfind(msg,'mlr'); % header for multilinear regressor
                    idxEnd = strfind(msg,'END');
                    if (~isempty(idx)) % Pattern Recognition --------------------------------------------------------->
                        idx = idx(1)+3;
                        if(idx < 130) % make sure that the entire message can be read
                            data = uint8(msg((idx):(idx+3))); % classifier type, output class, force level, prostprocessing algo.
                            data1 = int8(msg((idx+4):(idx+6))); % 3 distances related to the first 3 magnet pairs
                            data2 = int8(msg((idx+7):(idx+15))); % MVC levels for the first 3 pairs and first 3 classes (optional for future)
                            switch data(1)
                                case 1
                                    str = "SVM steady state";
                                case 2
                                    str = "SVM transient";
                                case 3
                                    str = "SVM steady state + MV";
                                otherwise
                                    str = num2str(data(1));
                            end
                            app.TypeLabel.Text = str;
                            class = InterpretClass(app,data(2));
    %                         class = num2str(data(2));
                            app.ClassLabel.Text = class;
                            switch data(4)
                                case 0
                                    str1 = "NONE";
                                case 1
                                    str1 = "Majority vote";
                                case 2
                                    str1 = "Grasp lock";
                                otherwise
                                    str1 = num2str(data(4));
                            end
                            app.PPTypeLabel.Text = str1;
                            app.ForceLabel.Text = num2str(data(3));
                            app.dist1Label.Text = num2str(data1(1));
                            app.dist2Label.Text = num2str(data1(2));
                            app.dist3Label.Text = num2str(data1(3));
                            drawnow;
                            if (cntMVC > 1000) % Updating the MVC levels
                                app.MVC11Label.Text = num2str(data2(1));
                                app.MVC12Label.Text = num2str(data2(2));
                                app.MVC13Label.Text = num2str(data2(3));
                                app.MVC21Label.Text = num2str(data2(4));
                                app.MVC22Label.Text = num2str(data2(5));
                                app.MVC23Label.Text = num2str(data2(6));
                                app.MVC31Label.Text = num2str(data2(7));
                                app.MVC32Label.Text = num2str(data2(8));
                                app.MVC33Label.Text = num2str(data2(9));
                                drawnow;
                                cntMVC = 0;
                            end
                        end
                    end
                    if (~isempty(idxM)) % Multilinear regressor --------------------------------------------------->
                        idxM = idxM(1) + 3;
                        if (idxM < 120)
                            if(flagPlotThreshold)
                                flagPlotThreshold = 0;
                            end
                            data = uint8(msg(idxM:(idxM+20))); % classifier identifier and MLR output
                            data1 = int8(msg((idxM+21):(idxM+23))); % magnet distances
                            data2 = uint8(msg((idxM+24):(idxM+26))); % encoder values
                            data3 = uint8(msg((idxM+27):(idxM+31))); % thresholds 
                            data3 = single(data3)./100;
                            if (data(1) == 4) % classifier/regressor identifier
                                app.TypeLabel.Text = 'Multilinear reg.';
                            else
                                app.TypeLabel.Text = num2str(data(1));
                            end
                            app.dist1Label.Text = num2str(data1(1));
                            app.dist2Label.Text = num2str(data1(2));
                            app.dist3Label.Text = num2str(data1(3));
                            drawnow;
                            for i = 1:5 % converting 4 uint8 bytes to one float
                                dataPlot(i) = typecast(data((1:4) + 4*(i-1) + 1),'single');
                            end
                            if (cntSP > 10) % Updating the figure
                                xA = [0.5, 1.5, 1.5, 2.5, 2.5, 3.5, 3.5, 4.5, 4.5, 5.5];
                                yA = [data3(1), data3(1), data3(2), data3(2), data3(3), data3(3), data3(4), data3(4), data3(5), data3(5)];
                                plot(app.UIAxes,xA, yA, 'r--');
                                hold(app.UIAxes,'on');
                                scatter(app.UIAxes,[1 2 3 4 5],dataPlot,80,'MarkerEdgeColor',[0 .5 .5], 'MarkerFaceColor',[0 .7 .7], 'LineWidth',1.5);
                                hold(app.UIAxes,'off');
                                drawnow;
                                cntSP = 0;
                            end
                            app.Enc1Label.Text = num2str(data2(1));
                            app.Enc2Label.Text = num2str(data2(2));
                            app.Enc3Label.Text = num2str(data2(3));
                            drawnow;
                        end
                    end
                end
%                 writematrix(data,'data.txt');
            else
%                 SendCommand(app,'B0');
%                 pause(0.5);
%                 SendCommand(app,'A0');
                app.Message.Text = "Streaming stopped";
                app.ClassLabel.Text = 'Class';
                app.ForceLabel.Text = 'Force';
                app.PPTypeLabel.Text = '';
                app.dist1Label.Text = '';
                app.dist2Label.Text = '';
                app.dist3Label.Text = '';
                drawnow;
            end
        end

        % Value changed function: AllowpatternrecognitionButton
        function AllowpatternrecognitionButtonValueChanged(app, event)
            value = app.AllowpatternrecognitionButton.Value;
            if isempty(app.obj)
                app.Message.Text = 'Error: No COM port open!';
                return;
            end
            if value 
                SendCommand(app,'PRA1');
                app.Message.Text = 'Pattern recognition allowed';
            else
                SendCommand(app,'PRA0');
                app.Message.Text = "Pattern recognition stopped";
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            app.Message.Text = 'Loading data from file...';
            [file, path] = uigetfile({'*.mat';'*.csv'});
            if ~isequal(file, 0)
                data = load([path,file]); 
                if isfield(data,'magPairs')
                    magPairsStr = ["Pair: (Mag1, Mag2)"];
                    dRestStr = ["Pair: distance"];
                    dMaxDispStr = ["Movement: displacement"];
                    % printing all the data in an text array
                    for i = 1:size(data.magPairs,1)
                        magPairsStr = [magPairsStr, num2str(i) + ": (" + num2str(data.magPairs(i,1))...
                            + ", " + num2str(data.magPairs(i,2)) + ")"];
                        dRestStr = [dRestStr, num2str(i) + ": " + num2str(data.d_rest(i))];
                    end
                    if (isfield(data,'displ_max'))
                        for i = 1:size(data.magPairs,1)
                            tempStr = "";
                            for j = 1:size(data.displ_max,1)
                                tempStr = tempStr + num2str(data.displ_max(j,i)) + "; ";
                            end
                            dMaxDispStr = [dMaxDispStr; num2str(i) + ": " + tempStr];
                        end
                    end
                    app.MagnetPairs.Text = magPairsStr;
                    app.RestDistance.Text = dRestStr;
                    app.MaximumDisplacement.Text = dMaxDispStr;
                    app.Message.Text = 'File loaded';
%                     app.UploadDataButton.Enable = "on";
                    app.UploadMagnetPairsButton.Enable = "on";
                    app.dataRestMax = data;
                    app.ChannelsListBox_2.Items = cellstr(num2str([1:size(data.magPairs,1)]'));
                    app.ChannelsListBox_2.Value = app.ChannelsListBox_2.Items;
                else
                    app.Message.Text = "Error: wrong file!";
                    return;
                end
            else
                app.Message.Text = "Error: file not chosen!";
            end
        end

        % Button pushed function: UploadDataButton
        function UploadDataButtonPushed(app, event)
            data = app.dataRestMax;
            if (~isfield(data,'displ_max'))
                data.displ_max = ones(1,length(data.d_rest));
            end
            header = 'hc';
            d_rest_char = [];
            d_max_char = [];
            for i = 1:length(data.d_rest)
                d_rest_char = [d_rest_char, num2str(floor(data.d_rest(i))), num2str(floor(data.d_rest(i)*10)),...
                    num2str(floor(data.d_rest(i)*100)), num2str(floor(data.d_rest(i)*1000))];
                d_max_char = [d_max_char, num2str(floor(data.displ_max(i))), num2str(floor(data.displ_max(i)*10)),...
                    num2str(floor(data.displ_max(i)*100)), num2str(floor(data.displ_max(i)*1000))];
            end
            command = [header, d_rest_char, d_max_char];
            if (isempty(app.obj))
                app.Message.Text = "Error: port not open!";
                return;
            end
            SendCommand(app,command);
            app.Message.Text = "Data sent";
        end

        % Button pushed function: UploadMagnetPairsButton
        function UploadMagnetPairsButtonPushed(app, event)
            msgL = 394; % 4 + 15*2 + 15*4 + 15*4*5
            if (~isfield(app.dataRestMax,'displ_max'))
                app.dataRestMax.displ_max = ones(5,length(app.dataRestMax.d_rest));
            end
            nPairs = uint8(length(app.ChannelsListBox_2.Value));
            magPairs = app.dataRestMax.magPairs;
            idxPairs = cell2mat(app.ChannelsListBox_2.Value');
            idxPairs = str2num(idxPairs);
            magPairs = magPairs(idxPairs,:);
            magPairs = reshape(magPairs',1,nPairs*2);
            magPairs = uint8(magPairs);
            dR = app.dataRestMax.d_rest;
            dR = dR(idxPairs);
            dR = Splitfloat(app,dR*1000);
            dM = app.dataRestMax.displ_max(:,idxPairs);
            dM = Splitfloat(app,reshape(dM',1,[]));
            header = 'PRM';
            command = [header, nPairs, magPairs, dR, dM];
            if length(command) ~= msgL
                command = [command, uint8(zeros(1,msgL-length(command)))];
            end
            SendCommand(app,command);
            app.Message.Text = "Magnet pairs sent";
        end

        % Button pushed function: LoadfeaturesButton
        function LoadfeaturesButtonPushed(app, event)
            app.Message.Text = 'Loading features file...';
            [file, path] = uigetfile({'*.mat';'*.csv'});
            if ~isequal(file, 0)
                feat = load([path,file]); 
                if isfield(feat,'sigFeatures')
                    app.sigFeat = feat.sigFeatures;
                    allFeatures = fieldnames(app.sigFeat.trFeatures);
                    allFeatures(contains(allFeatures,'tmabs')) = [];
                    app.FeaturesListBox.Items = allFeatures;
                    app.FeaturesListBox.Value = allFeatures;
                    app.DoFListBox.Items = app.sigFeat.mov;
                    app.DoFListBox.Value = app.sigFeat.mov(1:end-1);
                    app.ChannelsListBox.Items = num2cell(num2str(app.sigFeat.nCh(1:end-1)'),2);
                    app.ChannelsListBox.Value = num2cell(num2str(app.sigFeat.nCh(1:end-1)'),2);
                    app.TrainMLRButton.Enable = "on";
                    app.Message.Text = 'Feature file loaded.';
                else
                    app.Message.Text = 'Error: Wrong file!';
                end
            end
        end

        % Button pushed function: TrainMLRButton
        function TrainMLRButtonPushed(app, event)
            features = app.FeaturesListBox.Value; % chosen features
            [~,memF] = ismember(features,app.FeaturesListBox.Items);
            [~,sortIdxF] = sort(memF);
            features = features(sortIdxF); % sort the features to be in the same order as in BioPatRec
            dofs = app.DoFListBox.Value; % chosen DoF
            feat = app.sigFeat;
%             for i = 1:length(dofs)
%                 dofsNum(i) = find(strcmp(app.DoFListBox.Items,dofs{i})); % getting the indexes of chosen DoFs
%             end
            [~,dofsNum] = ismember(dofs,app.DoFListBox.Items);
            dofsNum = sort(dofsNum);
            numSamples = size(feat.transFeatures,1);
            nCh = length(feat.nCh) - 1; % last channel are the labels for regressor, so it should be separated from the features
%             nCh = length(feat.nCh); 
            data = zeros(numSamples,nCh*length(features),length(dofsNum)); 
            labels = zeros(numSamples,length(dofsNum));
            % stacking the features in a 3D matrix (samples x (features*channels) x DoFs)
            for i = 1:numSamples
                for j = 1:length(dofsNum)
                    for k = 1:length(features)
                        kidx = (1:nCh) + (k-1)*nCh;
                        temp = feat.transFeatures(i,dofsNum(j)).(features{k});
                        data(i,kidx,j) = temp(1:end-1);
%                         data(i,kidx,j) = temp;
                    end
                    labels(i,j) = feat.transFeatures(i,dofsNum(j)).tmabs(end);
                end
            end      
            % Claculating the regressor coefficients
            [K,S,R, meanD, stdD] = multiLinRegressor(app,data,labels);
            % Saving all relevant parameters in an app property
            app.mlrParam.Coeff = K;
            app.mlrParam.Channels = str2num(cell2mat(app.ChannelsListBox.Value'));
            app.mlrParam.Dof = dofs;
            app.mlrParam.Features = features;
            app.mlrParam.standardParam.param1 = meanD;
            app.mlrParam.standardParam.param2 = stdD;
            app.UploadMLRparametersButton.Enable = "on";
            app.Message.Text = "Regressor is trained.";
        end

        % Button pushed function: DisconnetctButton
        function DisconnetctButtonPushed(app, event)
            app.obj = [];
            app.Message.Text = "COM disconnected";
        end

        % Button pushed function: UploadMLRparametersButton
        function UploadMLRparametersButtonPushed(app, event)
            msgL = 11 + app.maxMagnetPairs*2 + app.maxMagnetPairs*app.maxFeatures*2*4*2 + app.maxDof*4 + app.maxFeatures*2*app.maxMagnetPairs*app.maxDof*4;
            header = 'MRP';
            if isempty(app.obj)
                app.Message.Text = 'Error: No COM port open';
                return;
            end
            nDofs = uint8(length(app.mlrParam.Dof));
            classifierCode = uint8(4);
            nPairs = uint8(size(app.sigFeat.magnetData.pairs,1));
            magPairs = uint8(reshape(app.sigFeat.magnetData.pairs',1,2*nPairs));
            featureCode = CreateFeatureCode(app,app.mlrParam.Features);
            featureSize = uint8(size(app.mlrParam.Coeff,1) - 1); %exclude the bias
            normParameter1 = Splitfloat(app,app.mlrParam.standardParam.param1);
            normParameter2 = Splitfloat(app,app.mlrParam.standardParam.param2);
            bias = Splitfloat(app,app.mlrParam.Coeff(1,:));
            w = app.mlrParam.Coeff(2:end,:);
            w = reshape(w,1,[]);
            w = Splitfloat(app,w);
            featureCodeMag = uint8(0);
            if (length(app.sigFeat.magnetData.Features) == 2)
                featureCodeMag = bitor(featureCodeMag,2^7+2^6);
            elseif contains(app.sigFeat.magnetData.Features{1},'Lin')
                featureCodeMag = bitor(featureCodeMag,2^7);
            else
                featureCodeMag = bitor(featureCodeMag,2^6);
            end
            channelsCode = uint32(0);
            for i = 1:length(app.mlrParam.Channels)
                channelsCode = bitor(channelsCode,2^(app.mlrParam.Channels(i)-1));
            end
            if channelsCode < 256
                channelsCode = char([0,0,0,channelsCode]);
            elseif channelsCode < 65536
                channelsCode = char([0,0,channelsCode]);
            elseif channelsCode < 16777216
                channelsCode = char([0,channelsCode]);
            end
            dofsCode = uint8(0);
            exponent = (1:length(app.DofNames)) - 1;
            for i = 1:length(app.mlrParam.Dof)
                dofsCode = bitor(dofsCode,2^exponent(strcmp(app.mlrParam.Dof(i),app.DofNames)));
            end
            % Forming a command
            command = [header, classifierCode, nDofs, featureCodeMag, featureCode, ...
                featureSize, channelsCode, dofsCode, nPairs, ...
                magPairs, normParameter1, normParameter2, bias, w];
            if length(command)~=msgL
                command = [command, uint8(zeros(1,msgL-length(command)))];
            end
            if ~isempty(app.obj)
                SendCommand(app,command);
                app.Message.Text = 'MLR parameters sent';
            else
                app.Message.Text = 'No connection!';
            end
        end

        % Value changed function: EnableMLRButton
        function EnableMLRButtonValueChanged(app, event)
            value = app.EnableMLRButton.Value;
            if value 
                SendCommand(app,'MRA1');
                app.Message.Text = 'Multilinear regression allowed';
            else
                SendCommand(app,'MRA0');
                app.Message.Text = "Multilinear regression stopped";
            end
        end

        % Button pushed function: FlushtheserialButton
        function FlushtheserialButtonPushed(app, event)
            if isempty(app.obj)
                app.Message.Text = 'Error: COM port not open!';
                return;
            else
                flush(app.obj);
                app.Message.Text = 'Serial port flushed.';
            end
        end

        % Button pushed function: SendctrlparamButton
        function SendctrlparamButtonPushed(app, event)
            header  = 'MRC';
            if isempty(app.obj)
                app.Message.Text = 'Error: No COM port open!';
                return;
            end
            if (app.VelocityButton.Value)
                ctrl = 'V';
            else
                ctrl = 'P';
            end
            threshold = zeros(1,5);
            gains = zeros(1,5);
            for i = 1:5
                field = app.(['DoF', num2str(i), 'EditField']);
                fieldG = app.(['Gain', num2str(i), 'EditField']);
                threshold(i) = uint8(field.Value);
                gains(i) = uint8(fieldG.Value);
            end
            command = [header, ctrl, threshold, gains];
            SendCommand(app,command);          
            app.Message.Text = 'Control values sent.';
        end

        % Value changed function: HandcontrolButton
        function HandcontrolButtonValueChanged(app, event)
            value = app.HandcontrolButton.Value;
            if isempty(app.obj)
                app.Message.Text = 'Error: No COM port open!';
                return;
            end
            if value
                SendCommand(app,'hgo');
                app.Message.Text = 'Hand control enabled.';
            else
                SendCommand(app,'hst');
                app.Message.Text = 'Hand control disabled.';
            end
        end

        % Button pushed function: ReadtheparametersButton
        function ReadtheparametersButtonPushed(app, event)
            if isempty(app.obj)
                app.Message.Text = 'Error: No COM port open!';
                return;
            end
            SendCommand(app,'S0');
            pause(0.5);
            flush(app.obj);
            SendCommand(app,'Pparam');
            idx = [];
            cnt = 0;
            while(isempty(idx))
                msg = read(app.obj,100,'char');
                idx = strfind(msg,'PRMR');
                if (idx > 20)
                    idx = [];
                end
                cnt = cnt+1;
                if (cnt > 10)
                    return;
                end
            end
            idx = idx(1);
            if strcmp(msg((idx):(idx+3)),'PRMR') % Header ----------------------------------->
                generalPstr = "General parameters: ";
                generalPstr = [generalPstr, ""];
                patRecstr = "Pattern Recognition parameters: ";
                patRecstr = [patRecstr, ""];
                mlrstr = "Multilin Regressor parameters: ";
                mlrstr = [mlrstr, ""];
                switch msg(idx+4) % Control Mode
                    case 'P'
                        generalPstr = [generalPstr, "Control Mode: Pattern Rec"];
                    case 'R'
                        generalPstr = [generalPstr, "Control Mode: Multilin Reg"];
                    otherwise
                        generalPstr = [generalPstr, "Control Mode: " + msg(5)];
                end
                data = uint8(msg((idx+5):end));
                currentIdx = 1;
                generalPstr = [generalPstr, "Time window: " + num2str(data(currentIdx)*50) + "ms"];
                currentIdx = currentIdx + 1;
                % Pattern Recognition ----------------------------------->
                patRecstr = [patRecstr, "No mag pairs: " + num2str(data(currentIdx))];
                currentIdx = currentIdx + 1;
                patRecstr = [patRecstr, "Mag pairs: "];
                for i = 1:data(currentIdx-1)
                    patRecstr = [patRecstr, "   (" + num2str(data(currentIdx + 2*(i-1))) + ", " + num2str(data(currentIdx + (2*(i-1)+1))) + ")"];
                end
                currentIdx = currentIdx + 18;
                patRecstr = [patRecstr, "Number of classes: " + num2str(data(currentIdx))];
                currentIdx = currentIdx + 1;
                patRecstr = [patRecstr, "Classes used: "];
                str = "";
                for i = 1:(data(currentIdx-1))
                    str = InterpretClass(app,data(currentIdx + (i-1)));
                    patRecstr = [patRecstr, "   " + str];
                end
                currentIdx = currentIdx + 5;
                str = "";
                switch (data(currentIdx))
                    case 1
                        str = "SVM Steady State";
                    case 2
                        str = "SVM Transient State";
                    otherwise
                        str = num2str(data(currentIdx));
                end
                patRecstr = [patRecstr, "Classifier used: " + str];
                currentIdx = currentIdx + 1;
                str = "";
                switch (data(currentIdx))
                    case 0
                        str = "None";
                    case 1
                        str = "Majority Vote";
                    case 2
                        str = "Grasp lock";
                    otherwise
                        str = num2str(data(currentIdx));
                end
                patRecstr = [patRecstr, "Post-process used: " + str];
                currentIdx = currentIdx + 1;
                temp = dec2bin(data(currentIdx));
                for i = 1:(7-length(temp))
                    temp = strcat('0',temp);
                end
                temp = temp(end:-1:1);
                str = "";
                for i = 1:7 % Printing features
                    if (temp(i) == '1')
                        switch (i)
                            case 1
                                feat = "tmd";
                            case 2
                                feat = "tmax";
                            case 3
                                feat = "tmin";
                            case 4
                                feat = "tmabsTr";
                            case 5
                                feat = "twl";
                            case 6
                                feat = "mslp";
                            case 7
                                feat = "mlv";    
                        end
                        str = str + feat + ", ";
                    end
                end
                patRecstr = [patRecstr, "Features used: " + str];
                currentIdx = currentIdx + 1;
                str = "";
                if(data(currentIdx) == 1)
                    str = "Linear dist";
                elseif (data(currentIdx) == 2)
                    str = "Angular dist";
                elseif (data(currentIdx) == 3)
                    str = "Linear and Angluar dist";
                end
                patRecstr = [patRecstr, "Magnet features used: " + str];
                currentIdx = currentIdx + 1;
                patRecstr = [patRecstr, "Hold Open to swicth: " + num2str(data(currentIdx))];
                currentIdx = currentIdx + 1;
                patRecstr = [patRecstr, "   Time and threshold: " + num2str(data(currentIdx)) + ", " + num2str(data(currentIdx+1))];
                currentIdx = currentIdx + 2;
                % Multilin Reg ------------------------------------------>
                if (msg(idx+4) ~= 'P')
                    mlrstr = [mlrstr, "No mag pairs: " + num2str(data(currentIdx))];
                    currentIdx = currentIdx + 1;
                    mlrstr = [mlrstr, "Mag pairs: "];
                    for i = 1:data(currentIdx-1)
                        mlrstr = [mlrstr, "   (" + num2str(data(currentIdx + 2*(i-1))) + ", " + num2str(data(currentIdx + (2*(i-1)+1))) + ")"];
                    end
                    currentIdx = currentIdx + 18;
                    mlrstr = [mlrstr, "Number of classes: " + num2str(data(currentIdx))];
                    currentIdx = currentIdx + 1;
                    temp = dec2bin(data(currentIdx));
                    for i = 1:(5-length(temp))
                        temp = strcat('0',temp);
                    end
                    temp = temp(end:-1:1);
                    str = "";
                    mlrstr = [mlrstr, "DoFs used: "];
                    for i = 1:5 % Printing Dofs
                        if(temp(i) == '1')
                            dof = app.DofNames{i};
                            mlrstr = [mlrstr, "    " + dof];
                        end
                    end
                    currentIdx = currentIdx + 1;
                    mlrstr = [mlrstr, "Active channels: " + dec2bin(data(currentIdx)*2^24 + data(currentIdx+1)*2^16 + data(currentIdx+2)*2^8 + data(currentIdx+3))];
                    currentIdx = currentIdx + 4;
                    temp = dec2bin(data(currentIdx));
                    temp = temp(end:-1:1);
                    str = "";
                    for i = 1:7 % Printing features
                        if (temp(i) == '1')
                            switch (i)
                                case 1
                                    feat = "tmd";
                                case 2
                                    feat = "tmax";
                                case 3
                                    feat = "tmin";
                                case 4
                                    feat = "tmabsTr";
                                case 5
                                    feat = "twl";
                                case 6
                                    feat = "mslp";
                                case 7
                                    feat = "mlv";    
                            end
                            str = str + feat + ", ";
                        end
                    end
                    mlrstr = [mlrstr, "Features used: " + str];
                    currentIdx = currentIdx + 1;
                    str = "";
                    if(data(currentIdx) == 1)
                        str = "Linear dist";
                    elseif (data(currentIdx) == 2)
                        str = "Angular dist";
                    elseif (data(currentIdx) == 3)
                        str = "Linear and Angluar dist";
                    end
                    mlrstr = [mlrstr, "Magnet features used: " + str];
                    currentIdx = currentIdx + 1;
                    mlrstr = [mlrstr, "Thresholds: " + num2str(data(currentIdx)) + ", " + num2str(data(currentIdx + 1)) + ", "...
                        + num2str(data(currentIdx + 2)) + ", " + num2str(data(currentIdx + 3)) + ", " + num2str(data(currentIdx + 4))];
                    currentIdx = currentIdx + 5;
                    mlrstr = [mlrstr, "Gains: " + num2str(data(currentIdx)) + ", " + num2str(data(currentIdx + 1)) + ", "...
                        + num2str(data(currentIdx + 2)) + ", " + num2str(data(currentIdx + 3)) + ", " + num2str(data(currentIdx + 4))];
                    currentIdx = currentIdx + 5;
                end
                % Display on interface --------------------------------->
                app.GeneralparametersLabel.Text = generalPstr;
                app.PatternRecognitionparametersLabel.Text = patRecstr;
                app.MultilinearRegressorparametersLabel.Text = mlrstr;
                drawnow;
                SendCommand(app,'S1');
                app.Message.Text = 'Parameters read.';
            else
                app.Message.Text = 'Error: Wrong message received';
            end
        end

        % Button pushed function: SendswitchparametersButton
        function SendswitchparametersButtonPushed(app, event)
            header = 'PRSW';
            enable = uint8(app.EnableSwitchCheckBox.Value);
            time = uint8(app.Waitforswitch150msEditField.Value);
            thresh = uint8(app.ThresholdtoswitchEditField.Value);
            command = [header, enable, time, thresh];
            if (isempty(app.obj))
                app.Message.Text = "Error: port not open!";
                return;
            end
            SendCommand(app,command);
            app.Message.Text = "Switch parameters sent";
        end

        % Button pushed function: SendfilterparametersButton
        function SendfilterparametersButtonPushed(app, event)
            header  = 'Fil';
            enable = uint8(app.EnableFilterCheckBox.Value);
            time = uint8(app.Filterwindow150msEditField.Value);
            thresh = uint8(app.FilterthresholdmmEditField.Value*10);
            command = [header, enable, time, thresh];
            if (isempty(app.obj))
                app.Message.Text = "Error: port not open!";
                return;
            end
            SendCommand(app,command);
            app.Message.Text = "Filter parameters sent";
        end

        % Button pushed function: CalibratemagposButton
        function CalibratemagposButtonPushed(app, event)
            header  = 'FCa';
            command = header;
            if (isempty(app.obj))
                app.Message.Text = "Error: port not open!";
                return;
            end
            SendCommand(app,command);
            app.Message.Text = "Magnets Calibrated";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ClassifierParametersTab = uitab(app.TabGroup);
            app.ClassifierParametersTab.Title = 'Classifier Parameters';
            app.ConnectionPanel = uipanel(app.ClassifierParametersTab);
            app.ConnectionPanel.Title = 'Connection';
            app.ConnectButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Tag = 'pb_Connect';
            app.ConnectButton.Text = 'Connect';
            app.ChooseCOMDropDownLabel = uilabel(app.ConnectionPanel);
            app.ChooseCOMDropDownLabel.Text = 'Choose COM';
            app.ChooseCOMDropDown = uidropdown(app.ConnectionPanel);
            app.ChooseCOMDropDown.Items = {};
            app.ChooseCOMDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ChooseCOMDropDownOpening, true);
            app.ChooseCOMDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseCOMDropDownValueChanged, true);
            app.ChooseCOMDropDown.Tag = 'dd_COM';
            app.ChooseCOMDropDown.Value = {};
            app.DisconnetctButton = uibutton(app.ConnectionPanel, 'push');
            app.DisconnetctButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnetctButtonPushed, true);
            app.DisconnetctButton.Text = 'Disconnetct';
            app.RefreshCOMButton = uibutton(app.ConnectionPanel, 'push');
            app.RefreshCOMButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshCOMButtonPushed, true);
            app.RefreshCOMButton.Text = 'Refresh COM';
            app.PatternRecognitionClassifierPanel = uipanel(app.ClassifierParametersTab);
            app.PatternRecognitionClassifierPanel.Title = 'Pattern Recognition Classifier';
            app.LoadfromfileButton = uibutton(app.PatternRecognitionClassifierPanel, 'push');
            app.LoadfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfromfileButtonPushed, true);
            app.LoadfromfileButton.Text = 'Load from file';
            app.ClassifierLabel = uilabel(app.PatternRecognitionClassifierPanel);
            app.ClassifierLabel.Text = 'Classifier';
            app.FeaturesLabel = uilabel(app.PatternRecognitionClassifierPanel);
            app.FeaturesLabel.Text = 'Features';
            app.MagFeaturesLabel = uilabel(app.PatternRecognitionClassifierPanel);
            app.MagFeaturesLabel.Text = 'Mag. Features';
            app.NumclassesLabel = uilabel(app.PatternRecognitionClassifierPanel);
            app.NumclassesLabel.Text = 'Num. classes';
            app.UploadtoboardButton = uibutton(app.PatternRecognitionClassifierPanel, 'push');
            app.UploadtoboardButton.ButtonPushedFcn = createCallbackFcn(app, @UploadtoboardButtonPushed, true);
            app.UploadtoboardButton.Enable = 'off';
            app.UploadtoboardButton.Text = 'Upload to board';
            app.ProportionalcontrolCheckBox = uicheckbox(app.PatternRecognitionClassifierPanel);
            app.ProportionalcontrolCheckBox.Text = 'Proportional control';
            app.EnableDisablepropctrlButton = uibutton(app.PatternRecognitionClassifierPanel, 'push');
            app.EnableDisablepropctrlButton.ButtonPushedFcn = createCallbackFcn(app, @EnableDisablepropctrlButtonPushed, true);
            app.EnableDisablepropctrlButton.Text = 'Enable/Disable prop.ctrl.';
            app.ChoosechannelsButton = uibutton(app.PatternRecognitionClassifierPanel, 'push');
            app.ChoosechannelsButton.Text = 'Choose channels';
            app.FeaturessettingsPanel = uipanel(app.ClassifierParametersTab);
            app.FeaturessettingsPanel.Title = 'Features settings';
            app.TimewindowmsEditFieldLabel = uilabel(app.FeaturessettingsPanel);
            app.TimewindowmsEditFieldLabel.Text = 'Time window (ms)';
            app.TimewindowmsEditField = uieditfield(app.FeaturessettingsPanel, 'text');
            app.TimewindowmsEditField.Value = '200';
            app.OverlapwindowsamplesEditFieldLabel = uilabel(app.FeaturessettingsPanel);
            app.OverlapwindowsamplesEditFieldLabel.Text = 'Overlap window (samples)';
            app.OverlapwindowsamplesEditField = uieditfield(app.FeaturessettingsPanel, 'text');
            app.OverlapwindowsamplesEditField.Enable = 'off';
            app.OverlapwindowsamplesEditField.Value = '1';
            app.SendfeatparamButton = uibutton(app.FeaturessettingsPanel, 'push');
            app.SendfeatparamButton.ButtonPushedFcn = createCallbackFcn(app, @SendfeatparamButtonPushed, true);
            app.SendfeatparamButton.Text = 'Send feat. param.';
            app.FeaturesPanel = uipanel(app.FeaturessettingsPanel);
            app.FeaturesPanel.Title = 'Features';
            app.MAVCheckBox = uicheckbox(app.FeaturesPanel);
            app.MAVCheckBox.Enable = 'off';
            app.MAVCheckBox.Text = 'MAV';
            app.MedianCheckBox = uicheckbox(app.FeaturesPanel);
            app.MedianCheckBox.Enable = 'off';
            app.MedianCheckBox.Text = 'Median';
            app.MaxCheckBox = uicheckbox(app.FeaturesPanel);
            app.MaxCheckBox.Enable = 'off';
            app.MaxCheckBox.Text = 'Max';
            app.MinCheckBox = uicheckbox(app.FeaturesPanel);
            app.MinCheckBox.Enable = 'off';
            app.MinCheckBox.Text = 'Min';
            app.WLCheckBox = uicheckbox(app.FeaturesPanel);
            app.WLCheckBox.Enable = 'off';
            app.WLCheckBox.Text = 'WL';
            app.SlopeCheckBox = uicheckbox(app.FeaturesPanel);
            app.SlopeCheckBox.Enable = 'off';
            app.SlopeCheckBox.Text = 'Slope';
            app.LastvalueCheckBox = uicheckbox(app.FeaturesPanel);
            app.LastvalueCheckBox.Enable = 'off';
            app.LastvalueCheckBox.Text = 'Last value';
            app.WaitforODA150msEditField_2Label = uilabel(app.FeaturessettingsPanel);
            app.WaitforODA150msEditField_2Label.Text = 'Wait for ODA (1 = 50ms)';
            app.WaitforODAEditField = uieditfield(app.FeaturessettingsPanel, 'numeric');
            app.WaitforODAEditField.Value = 1;
            app.WaitforMV150msEditFieldLabel = uilabel(app.FeaturessettingsPanel);
            app.WaitforMV150msEditFieldLabel.Text = 'Wait for MV (1 = 50ms)';
            app.WaitforMVEditField = uieditfield(app.FeaturessettingsPanel, 'numeric');
            app.WaitforMVEditField.Value = 5;
            app.AllowpatternrecognitionButton = uibutton(app.FeaturessettingsPanel, 'state');
            app.AllowpatternrecognitionButton.ValueChangedFcn = createCallbackFcn(app, @AllowpatternrecognitionButtonValueChanged, true);
            app.AllowpatternrecognitionButton.Text = 'Allow pattern recognition';
            app.PostprocessingalgorithmButtonGroup = uibuttongroup(app.FeaturessettingsPanel);
            app.PostprocessingalgorithmButtonGroup.Title = 'Post-processing algorithm';
            app.NoneButton = uiradiobutton(app.PostprocessingalgorithmButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.MajorityvoteButton = uiradiobutton(app.PostprocessingalgorithmButtonGroup);
            app.MajorityvoteButton.Text = 'Majority vote';
            app.GrasplockButton = uiradiobutton(app.PostprocessingalgorithmButtonGroup);
            app.GrasplockButton.Text = 'Grasp lock';
            app.ButtoncolorlegendPanel = uipanel(app.ClassifierParametersTab);
            app.ButtoncolorlegendPanel.Title = 'Button color legend';
            app.SendingcommandtoboardLabel = uilabel(app.ButtoncolorlegendPanel);
            app.SendingcommandtoboardLabel.Text = 'Sending command to board';
            app.LoadingdataontheappLabel = uilabel(app.ButtoncolorlegendPanel);
            app.LoadingdataontheappLabel.Text = 'Loading data on the app';
            app.EnablingDiablingcontrolontheboardLabel = uilabel(app.ButtoncolorlegendPanel);
            app.EnablingDiablingcontrolontheboardLabel.Text = 'Enabling/Diabling control on the board';
            app.ObtainingparameterswithingtheappLabel = uilabel(app.ButtoncolorlegendPanel);
            app.ObtainingparameterswithingtheappLabel.Text = 'Obtaining parameters withing the app';
            app.RemovingconnectionwiththeboardLabel = uilabel(app.ButtoncolorlegendPanel);
            app.RemovingconnectionwiththeboardLabel.Text = 'Removing connection with the board';
            app.ClassonBioPatRectoMIAlegendPanel = uipanel(app.ClassifierParametersTab);
            app.ClassonBioPatRectoMIAlegendPanel.Title = 'Class on BioPatRec to MIA legend';
            app.Cylindrical3Label = uilabel(app.ClassonBioPatRectoMIAlegendPanel);
            app.Cylindrical3Label.Text = 'Cylindrical = 3';
            app.Openhand5Label = uilabel(app.ClassonBioPatRectoMIAlegendPanel);
            app.Openhand5Label.Text = 'Open hand = 5';
            app.Pinch4Label = uilabel(app.ClassonBioPatRectoMIAlegendPanel);
            app.Pinch4Label.Text = 'Pinch = 4';
            app.Lateral6Label = uilabel(app.ClassonBioPatRectoMIAlegendPanel);
            app.Lateral6Label.Text = 'Lateral = 6';
            app.HoldtoopenparametersPanel = uipanel(app.ClassifierParametersTab);
            app.HoldtoopenparametersPanel.Title = 'Hold to open parameters';
            app.EnableSwitchCheckBox = uicheckbox(app.HoldtoopenparametersPanel);
            app.EnableSwitchCheckBox.Text = 'Enable Switch';
            app.Waitforswitch150msEditFieldLabel = uilabel(app.HoldtoopenparametersPanel);
            app.Waitforswitch150msEditFieldLabel.Text = 'Wait for switch (1=50ms)';
            app.Waitforswitch150msEditField = uieditfield(app.HoldtoopenparametersPanel, 'numeric');
            app.Waitforswitch150msEditField.Value = 20;
            app.ThresholdtoswitchEditFieldLabel = uilabel(app.HoldtoopenparametersPanel);
            app.ThresholdtoswitchEditFieldLabel.Text = 'Threshold to switch';
            app.ThresholdtoswitchEditField = uieditfield(app.HoldtoopenparametersPanel, 'numeric');
            app.ThresholdtoswitchEditField.Value = 50;
            app.SendswitchparametersButton = uibutton(app.HoldtoopenparametersPanel, 'push');
            app.SendswitchparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SendswitchparametersButtonPushed, true);
            app.SendswitchparametersButton.Text = 'Send switch parameters';
            app.ElbowfilterPanel = uipanel(app.ClassifierParametersTab);
            app.ElbowfilterPanel.Title = 'Elbow filter';
            app.Filterwindow150msEditFieldLabel = uilabel(app.ElbowfilterPanel);
            app.Filterwindow150msEditFieldLabel.Text = 'Filter window (1 = 50ms)';
            app.Filterwindow150msEditField = uieditfield(app.ElbowfilterPanel, 'numeric');
            app.Filterwindow150msEditField.Value = 4;
            app.FilterthresholdmmEditFieldLabel = uilabel(app.ElbowfilterPanel);
            app.FilterthresholdmmEditFieldLabel.Text = 'Filter threshold (mm)';
            app.FilterthresholdmmEditField = uieditfield(app.ElbowfilterPanel, 'numeric');
            app.FilterthresholdmmEditField.Value = 2.5;
            app.EnableFilterCheckBox = uicheckbox(app.ElbowfilterPanel);
            app.EnableFilterCheckBox.Text = 'Enable Filter';
            app.SendfilterparametersButton = uibutton(app.ElbowfilterPanel, 'push');
            app.SendfilterparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SendfilterparametersButtonPushed, true);
            app.SendfilterparametersButton.Text = 'Send filter parameters';
            app.CalibratemagposButton = uibutton(app.ElbowfilterPanel, 'push');
            app.CalibratemagposButton.ButtonPushedFcn = createCallbackFcn(app, @CalibratemagposButtonPushed, true);
            app.CalibratemagposButton.Text = 'Calibrate mag pos';
            app.StreamingTab = uitab(app.TabGroup);
            app.StreamingTab.Title = 'Streaming';
            app.UIAxes = uiaxes(app.StreamingTab);
            title(app.UIAxes, 'MLR values')
            xlabel(app.UIAxes, 'Pairs')
            ylabel(app.UIAxes, 'Distances')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.StreamButton = uibutton(app.StreamingTab, 'state');
            app.StreamButton.ValueChangedFcn = createCallbackFcn(app, @StreamButtonValueChanged, true);
            app.StreamButton.Text = 'Stream';
            app.PredictedclassLabel = uilabel(app.StreamingTab);
            app.PredictedclassLabel.Text = 'Predicted class';
            app.ClassLabel = uilabel(app.StreamingTab);
            app.ClassLabel.Text = 'Class';
            app.ProportionalcontrolLabel = uilabel(app.StreamingTab);
            app.ProportionalcontrolLabel.Text = 'Proportional control (%)';
            app.ForceLabel = uilabel(app.StreamingTab);
            app.ForceLabel.Text = 'Force';
            app.ClassifierLabel_2 = uilabel(app.StreamingTab);
            app.ClassifierLabel_2.Text = 'Classifier';
            app.TypeLabel = uilabel(app.StreamingTab);
            app.TypeLabel.Text = 'Type';
            app.PostprocessingalgorithmLabel = uilabel(app.StreamingTab);
            app.PostprocessingalgorithmLabel.Text = 'Post-processing algorithm';
            app.PPTypeLabel = uilabel(app.StreamingTab);
            app.PPTypeLabel.Text = 'Type';
            app.AdditionalinfoPanel = uipanel(app.StreamingTab);
            app.AdditionalinfoPanel.Title = 'Additional info';
            app.dist1Label = uilabel(app.AdditionalinfoPanel);
            app.dist1Label.Text = 'dist1';
            app.dist2Label = uilabel(app.AdditionalinfoPanel);
            app.dist2Label.Text = 'dist2';
            app.dist3Label = uilabel(app.AdditionalinfoPanel);
            app.dist3Label.Text = 'dist3';
            app.MVC11Label = uilabel(app.AdditionalinfoPanel);
            app.MVC11Label.Text = 'MVC11';
            app.MVC12Label = uilabel(app.AdditionalinfoPanel);
            app.MVC12Label.Text = 'MVC12';
            app.MVC13Label = uilabel(app.AdditionalinfoPanel);
            app.MVC13Label.Text = 'MVC13';
            app.MVC21Label = uilabel(app.AdditionalinfoPanel);
            app.MVC21Label.Text = 'MVC21';
            app.MVC22Label = uilabel(app.AdditionalinfoPanel);
            app.MVC22Label.Text = 'MVC22';
            app.MVC23Label = uilabel(app.AdditionalinfoPanel);
            app.MVC23Label.Text = 'MVC23';
            app.MVC31Label = uilabel(app.AdditionalinfoPanel);
            app.MVC31Label.Text = 'MVC31';
            app.MVC32Label = uilabel(app.AdditionalinfoPanel);
            app.MVC32Label.Text = 'MVC32';
            app.MVC33Label = uilabel(app.AdditionalinfoPanel);
            app.MVC33Label.Text = 'MVC33';
            app.Dist1Label = uilabel(app.AdditionalinfoPanel);
            app.Dist1Label.Text = 'Dist 1:';
            app.Dist2Label = uilabel(app.AdditionalinfoPanel);
            app.Dist2Label.Text = 'Dist 2:';
            app.Dist3Label = uilabel(app.AdditionalinfoPanel);
            app.Dist3Label.Text = 'Dist 3:';
            app.MVCperchannelsLabel = uilabel(app.AdditionalinfoPanel);
            app.MVCperchannelsLabel.Text = 'MVC per channels';
            app.Pair1Label = uilabel(app.AdditionalinfoPanel);
            app.Pair1Label.Text = 'Pair 1';
            app.Pair2Label = uilabel(app.AdditionalinfoPanel);
            app.Pair2Label.Text = 'Pair 2';
            app.Pair3Label = uilabel(app.AdditionalinfoPanel);
            app.Pair3Label.Text = 'Pair 3';
            app.C1Label = uilabel(app.AdditionalinfoPanel);
            app.C1Label.Text = 'C1';
            app.C2Label = uilabel(app.AdditionalinfoPanel);
            app.C2Label.Text = 'C2';
            app.C3Label = uilabel(app.AdditionalinfoPanel);
            app.C3Label.Text = 'C3';
            app.Enc1Label = uilabel(app.AdditionalinfoPanel);
            app.Enc1Label.Text = '';
            app.EncodervaluesLabel = uilabel(app.AdditionalinfoPanel);
            app.EncodervaluesLabel.Text = 'Encoder values';
            app.Mot1Label = uilabel(app.AdditionalinfoPanel);
            app.Mot1Label.Text = 'Mot 1:';
            app.Enc2Label = uilabel(app.AdditionalinfoPanel);
            app.Enc2Label.Text = '';
            app.Mot2Label = uilabel(app.AdditionalinfoPanel);
            app.Mot2Label.Text = 'Mot 2:';
            app.Enc3Label = uilabel(app.AdditionalinfoPanel);
            app.Enc3Label.Text = '';
            app.Mot3Label = uilabel(app.AdditionalinfoPanel);
            app.Mot3Label.Text = 'Mot 3:';
            app.MagnetpairdistancesLabel = uilabel(app.AdditionalinfoPanel);
            app.MagnetpairdistancesLabel.Text = 'Magnet pair distances';
            app.FlushtheserialButton = uibutton(app.StreamingTab, 'push');
            app.FlushtheserialButton.ButtonPushedFcn = createCallbackFcn(app, @FlushtheserialButtonPushed, true);
            app.FlushtheserialButton.Text = 'Flush the serial';
            app.RestandMaxdispTab = uitab(app.TabGroup);
            app.RestandMaxdispTab.Title = 'Rest and Max disp';
            app.LoadDataButton = uibutton(app.RestandMaxdispTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.MagnetPairsLabel = uilabel(app.RestandMaxdispTab);
            app.MagnetPairsLabel.Text = 'Magnet Pairs';
            app.MagnetPairs = uilabel(app.RestandMaxdispTab);
            app.MagnetPairs.Text = 'Pair: (Mag1, Mag2)';
            app.RestdistanceLabel = uilabel(app.RestandMaxdispTab);
            app.RestdistanceLabel.Text = 'Rest distance';
            app.RestDistance = uilabel(app.RestandMaxdispTab);
            app.RestDistance.Text = 'Pair: distance';
            app.MaximumdisplacementLabel = uilabel(app.RestandMaxdispTab);
            app.MaximumdisplacementLabel.Text = 'Maximum displacement';
            app.MaximumDisplacement = uilabel(app.RestandMaxdispTab);
            app.MaximumDisplacement.Text = 'Movement: displacement';
            app.UploadDataButton = uibutton(app.RestandMaxdispTab, 'push');
            app.UploadDataButton.ButtonPushedFcn = createCallbackFcn(app, @UploadDataButtonPushed, true);
            app.UploadDataButton.Enable = 'off';
            app.UploadDataButton.Text = 'Upload Data';
            app.UploadMagnetPairsButton = uibutton(app.RestandMaxdispTab, 'push');
            app.UploadMagnetPairsButton.ButtonPushedFcn = createCallbackFcn(app, @UploadMagnetPairsButtonPushed, true);
            app.UploadMagnetPairsButton.Enable = 'off';
            app.UploadMagnetPairsButton.Text = 'Upload Magnet Pairs';
            app.ChannelsListBox_2Label = uilabel(app.RestandMaxdispTab);
            app.ChannelsListBox_2Label.Text = 'Channels';
            app.ChannelsListBox_2 = uilistbox(app.RestandMaxdispTab);
            app.ChannelsListBox_2.Items = {};
            app.ChannelsListBox_2.Multiselect = 'on';
            app.ChannelsListBox_2.Value = {};
            app.MultilinearRegressorTab = uitab(app.TabGroup);
            app.MultilinearRegressorTab.Title = 'Multilinear Regressor';
            app.MLRfittingPanel = uipanel(app.MultilinearRegressorTab);
            app.MLRfittingPanel.Title = 'MLR fitting';
            app.FeaturesListBoxLabel = uilabel(app.MLRfittingPanel);
            app.FeaturesListBoxLabel.Text = 'Features';
            app.FeaturesListBox = uilistbox(app.MLRfittingPanel);
            app.FeaturesListBox.Items = {};
            app.FeaturesListBox.Multiselect = 'on';
            app.FeaturesListBox.Value = {};
            app.DoFListBoxLabel = uilabel(app.MLRfittingPanel);
            app.DoFListBoxLabel.Text = 'DoF';
            app.DoFListBox = uilistbox(app.MLRfittingPanel);
            app.DoFListBox.Items = {};
            app.DoFListBox.Multiselect = 'on';
            app.DoFListBox.Value = {};
            app.ChannelsListBoxLabel = uilabel(app.MLRfittingPanel);
            app.ChannelsListBoxLabel.Text = 'Channels';
            app.ChannelsListBox = uilistbox(app.MLRfittingPanel);
            app.ChannelsListBox.Items = {};
            app.ChannelsListBox.Multiselect = 'on';
            app.ChannelsListBox.Value = {};
            app.TrainMLRButton = uibutton(app.MLRfittingPanel, 'push');
            app.TrainMLRButton.ButtonPushedFcn = createCallbackFcn(app, @TrainMLRButtonPushed, true);
            app.TrainMLRButton.Enable = 'off';
            app.TrainMLRButton.Text = 'Train MLR';
            app.LoadfeaturesButton = uibutton(app.MLRfittingPanel, 'push');
            app.LoadfeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfeaturesButtonPushed, true);
            app.LoadfeaturesButton.Text = 'Load features';
            app.UploadMLRparametersButton = uibutton(app.MLRfittingPanel, 'push');
            app.UploadMLRparametersButton.ButtonPushedFcn = createCallbackFcn(app, @UploadMLRparametersButtonPushed, true);
            app.UploadMLRparametersButton.Enable = 'off';
            app.UploadMLRparametersButton.Text = 'Upload MLR parameters';
            app.EnableMLRButton = uibutton(app.MLRfittingPanel, 'state');
            app.EnableMLRButton.ValueChangedFcn = createCallbackFcn(app, @EnableMLRButtonValueChanged, true);
            app.EnableMLRButton.Text = 'Enable MLR';
            app.MLRcontrolPanel = uipanel(app.MLRfittingPanel);
            app.MLRcontrolPanel.Title = 'MLR control';
            app.ControlmodalityButtonGroup = uibuttongroup(app.MLRcontrolPanel);
            app.ControlmodalityButtonGroup.Title = 'Control modality';
            app.VelocityButton = uiradiobutton(app.ControlmodalityButtonGroup);
            app.VelocityButton.Text = 'Velocity';
            app.VelocityButton.Value = true;
            app.PositionButton = uiradiobutton(app.ControlmodalityButtonGroup);
            app.PositionButton.Enable = 'off';
            app.PositionButton.Text = 'Position';
            app.DoF1EditFieldLabel = uilabel(app.MLRcontrolPanel);
            app.DoF1EditFieldLabel.Text = 'DoF1';
            app.DoF1EditField = uieditfield(app.MLRcontrolPanel, 'numeric');
            app.DoF1EditField.Value = 25;
            app.DoF2EditFieldLabel = uilabel(app.MLRcontrolPanel);
            app.DoF2EditFieldLabel.Text = 'DoF2';
            app.DoF2EditField = uieditfield(app.MLRcontrolPanel, 'numeric');
            app.DoF2EditField.Value = 25;
            app.DoF3EditFieldLabel = uilabel(app.MLRcontrolPanel);
            app.DoF3EditFieldLabel.Text = 'DoF3';
            app.DoF3EditField = uieditfield(app.MLRcontrolPanel, 'numeric');
            app.DoF3EditField.Value = 25;
            app.DoF4EditFieldLabel = uilabel(app.MLRcontrolPanel);
            app.DoF4EditFieldLabel.Text = 'DoF4';
            app.DoF4EditField = uieditfield(app.MLRcontrolPanel, 'numeric');
            app.DoF4EditField.Value = 25;
            app.DoF5EditFieldLabel = uilabel(app.MLRcontrolPanel);
            app.DoF5EditFieldLabel.Text = 'DoF5';
            app.DoF5EditField = uieditfield(app.MLRcontrolPanel, 'numeric');
            app.DoF5EditField.Value = 25;
            app.SendctrlparamButton = uibutton(app.MLRcontrolPanel, 'push');
            app.SendctrlparamButton.ButtonPushedFcn = createCallbackFcn(app, @SendctrlparamButtonPushed, true);
            app.SendctrlparamButton.Text = 'Send ctrl param';
            app.ThresholdsLabel = uilabel(app.MLRcontrolPanel);
            app.ThresholdsLabel.Text = 'Thresholds:';
            app.GainsLabel = uilabel(app.MLRcontrolPanel);
            app.GainsLabel.Text = 'Gains:';
            app.DoF1EditField_2Label = uilabel(app.MLRcontrolPanel);
            app.DoF1EditField_2Label.Text = 'DoF1';
            app.Gain1EditField = uieditfield(app.MLRcontrolPanel, 'numeric');
            app.Gain1EditField.Value = 100;
            app.DoF2EditField_2Label = uilabel(app.MLRcontrolPanel);
            app.DoF2EditField_2Label.Text = 'DoF2';
            app.Gain2EditField = uieditfield(app.MLRcontrolPanel, 'numeric');
            app.Gain2EditField.Value = 100;
            app.DoF3EditField_2Label = uilabel(app.MLRcontrolPanel);
            app.DoF3EditField_2Label.Text = 'DoF3';
            app.Gain3EditField = uieditfield(app.MLRcontrolPanel, 'numeric');
            app.Gain3EditField.Value = 100;
            app.DoF4EditField_2Label = uilabel(app.MLRcontrolPanel);
            app.DoF4EditField_2Label.Text = 'DoF4';
            app.Gain4EditField = uieditfield(app.MLRcontrolPanel, 'numeric');
            app.Gain4EditField.Value = 100;
            app.DoF5EditField_2Label = uilabel(app.MLRcontrolPanel);
            app.DoF5EditField_2Label.Text = 'DoF5';
            app.Gain5EditField = uieditfield(app.MLRcontrolPanel, 'numeric');
            app.Gain5EditField.Value = 100;
            app.HandcontrolButton = uibutton(app.MLRcontrolPanel, 'state');
            app.HandcontrolButton.ValueChangedFcn = createCallbackFcn(app, @HandcontrolButtonValueChanged, true);
            app.HandcontrolButton.Text = 'Hand control';
            app.MLRvisualizationPanel = uipanel(app.MultilinearRegressorTab);
            app.MLRvisualizationPanel.Title = 'MLR visualization';
            app.ReadSavedparametersTab = uitab(app.TabGroup);
            app.ReadSavedparametersTab.Title = 'Read Saved parameters';
            app.ReadtheparametersButton = uibutton(app.ReadSavedparametersTab, 'push');
            app.ReadtheparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ReadtheparametersButtonPushed, true);
            app.ReadtheparametersButton.Text = 'Read the parameters';
            app.GeneralparametersLabel = uilabel(app.ReadSavedparametersTab);
            app.GeneralparametersLabel.Text = 'General parameters';
            app.PatternRecognitionparametersLabel = uilabel(app.ReadSavedparametersTab);
            app.PatternRecognitionparametersLabel.Text = 'Pattern Recognition parameters';
            app.MultilinearRegressorparametersLabel = uilabel(app.ReadSavedparametersTab);
            app.MultilinearRegressorparametersLabel.Text = 'Multilinear Regressor parameters';
            app.Message = uilabel(app.UIFigure);
            app.Message.Tag = 't_msg';
            app.Message.Text = 'Welcome';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MYKI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end