classdef diagApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        OKButton  matlab.ui.control.Button
        UITable   matlab.ui.control.Table
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            app.UITable.Data = [(1:app.CallingApp.patRec.nM)',zeros(app.CallingApp.patRec.nM,1)];
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            data = app.UITable.Data(:,2);
            UpdateProstheticOutput(app.CallingApp, data);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Class'; 'Prostehtic out'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = diagApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end